classdef tLine_advanced < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        CharacteristicImpedanceLabel   matlab.ui.control.Label
        CharacteristicImpedanceEditField  matlab.ui.control.NumericEditField
        PlayButton                     matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        LoadImpedanceEditField_2Label  matlab.ui.control.Label
        LoadImpedanceEditField         matlab.ui.control.EditField
        ForwardVoltageWaveCheckBox     matlab.ui.control.CheckBox
        ReverseVoltageWaveCheckBox     matlab.ui.control.CheckBox
        TotalVoltageWaveCheckBox       matlab.ui.control.CheckBox
        StandingVoltageWaveCheckBox    matlab.ui.control.CheckBox
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        %default values
        play = true;
        position = 0;
        Zo = 50;
        ZL = 50;
        %values needed of calculation
        freq = 300000000; %3Mhz
        lambda = 1;
        beta = 2*pi;
        gamma = 1j*2*pi;
        omega = 2*pi*300000000; % 2*pi*f
        ref = 0; % reflection coefficient
        %check box values
        forVol = true;
        revVol = true;
        totVol = true;
        stdVol = true;
    end
    
    methods (Access = private)
        
        function plotWaves(app,T)
            t=6.28*T/100/app.omega; %increment through the time, to run two periods
            z=linspace(0,2*app.lambda,80); %initialize vector for distance values along the t-line
            Vfor=real((exp(-app.gamma*z)*exp(1j*app.omega*t)));   %compute the forward power
            Vrev=real(app.ref*exp(app.gamma*z)*exp(1j*app.omega*t));  %compute the reverse voltage (power)
            Vtot=Vfor+Vrev;  %compute the total voltage (power)
            Vswr=((exp(-app.gamma*z)+app.ref*exp(app.gamma*z)).*conj((exp(-app.gamma*z)+app.ref*exp(app.gamma*z)))).^0.5;  %computer the standing wave ratio power
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            if(app.forVol == true)
                plot(app.UIAxes,z/app.lambda,Vfor,'m--');
            end
            if(app.revVol == true)
                plot(app.UIAxes,z/app.lambda,Vrev,'r--');
            end
            if(app.totVol == true)
                plot(app.UIAxes,z/app.lambda,Vtot,'Color',[0,0,0],"LineWidth",2);
            end
            if(app.stdVol == true)
                plot(app.UIAxes,z/app.lambda,Vswr,'b.');   %plot the four power signals
            end
            drawnow %display the updated plot
            hold(app.UIAxes,'off')
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.play = true;
            brk = false;
            while(brk == false && app.play == true)
                for T=app.position:300 %move through time
                    if(app.play == false)
                        break;
                    end
                    try
                    app.plotWaves(T);
                    catch
                        brk = true;
                        break;
                    end
                    %pause(.1)  %pause to control the speed of the simulation
                    if(T == 300)
                        app.position = 0;
                    else
                        app.position = T;
                    end
                end
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.play = false;
        end

        % Value changed function: CharacteristicImpedanceEditField
        function CharacteristicImpedanceEditFieldValueChanged(app, event)
            app.Zo = app.CharacteristicImpedanceEditField.Value;
            app.ref = (app.ZL - app.Zo)/(app.ZL + app.Zo);
            app.plotWaves(app.position);
            %calcute zo when its value is changed
        end

        % Value changed function: LoadImpedanceEditField
        function LoadImpedanceEditFieldValueChanged(app, event)
            app.ZL = eval(app.LoadImpedanceEditField.Value);
            app.ref = (app.ZL - app.Zo)/(app.ZL + app.Zo);
            app.plotWaves(app.position);
            %to calculte zl when its change
        end

        % Value changed function: ForwardVoltageWaveCheckBox
        function ForwardVoltageWaveCheckBoxValueChanged(app, event)
            app.forVol = app.ForwardVoltageWaveCheckBox.Value;
            app.plotWaves(app.position);
        end

        % Value changed function: ReverseVoltageWaveCheckBox
        function ReverseVoltageWaveCheckBoxValueChanged(app, event)
            app.revVol = app.ReverseVoltageWaveCheckBox.Value;
            app.plotWaves(app.position);
        end

        % Value changed function: TotalVoltageWaveCheckBox
        function TotalVoltageWaveCheckBoxValueChanged(app, event)
            app.totVol = app.TotalVoltageWaveCheckBox.Value;
            app.plotWaves(app.position);
        end

        % Value changed function: StandingVoltageWaveCheckBox
        function StandingVoltageWaveCheckBoxValueChanged(app, event)
            app.stdVol = app.StandingVoltageWaveCheckBox.Value;
            app.plotWaves(app.position);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transmission Line Voltages')
            xlabel(app.UIAxes, 'Position along transmission line (𝜆)')
            ylabel(app.UIAxes, 'Voltage (Vg)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 2];
            app.UIAxes.YLim = [-2 2];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.CharacteristicImpedanceLabel = uilabel(app.UIFigure);
            app.CharacteristicImpedanceLabel.Text = 'Characteristic Impedance (𝛺)';
            app.CharacteristicImpedanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CharacteristicImpedanceEditField.ValueChangedFcn = createCallbackFcn(app, @CharacteristicImpedanceEditFieldValueChanged, true);
            app.CharacteristicImpedanceEditField.Value = 50;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.LoadImpedanceEditField_2Label = uilabel(app.UIFigure);
            app.LoadImpedanceEditField_2Label.Text = 'Load Impedance (𝛺)';
            app.LoadImpedanceEditField = uieditfield(app.UIFigure, 'text');
            app.LoadImpedanceEditField.ValueChangedFcn = createCallbackFcn(app, @LoadImpedanceEditFieldValueChanged, true);
            app.LoadImpedanceEditField.Value = '50';
            app.ForwardVoltageWaveCheckBox = uicheckbox(app.UIFigure);
            app.ForwardVoltageWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForwardVoltageWaveCheckBoxValueChanged, true);
            app.ForwardVoltageWaveCheckBox.Text = 'Forward Voltage Wave';
            app.ForwardVoltageWaveCheckBox.Value = true;
            app.ReverseVoltageWaveCheckBox = uicheckbox(app.UIFigure);
            app.ReverseVoltageWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseVoltageWaveCheckBoxValueChanged, true);
            app.ReverseVoltageWaveCheckBox.Text = 'Reverse Voltage Wave';
            app.ReverseVoltageWaveCheckBox.Value = true;
            app.TotalVoltageWaveCheckBox = uicheckbox(app.UIFigure);
            app.TotalVoltageWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @TotalVoltageWaveCheckBoxValueChanged, true);
            app.TotalVoltageWaveCheckBox.Text = 'Total Voltage Wave';
            app.TotalVoltageWaveCheckBox.Value = true;
            app.StandingVoltageWaveCheckBox = uicheckbox(app.UIFigure);
            app.StandingVoltageWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @StandingVoltageWaveCheckBoxValueChanged, true);
            app.StandingVoltageWaveCheckBox.Text = 'Standing Voltage Wave';
            app.StandingVoltageWaveCheckBox.Value = true;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'legend.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tLine_advanced

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end